classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultGroup                     matlab.ui.container.TabGroup
        RsumTab                         matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        RsultatsTab                     matlab.ui.container.Tab
        LesAccords2Label                matlab.ui.control.Label
        SimulerButton                   matlab.ui.control.Button
        EnregistrerButton               matlab.ui.control.Button
        ChargerButton                   matlab.ui.control.Button
        RinitialiserButton              matlab.ui.control.Button
        StatutLabel                     matlab.ui.control.Label
        ParameterGroup                  matlab.ui.container.TabGroup
        IntrantsTab                     matlab.ui.container.Tab
        MasseliniqueEditFieldLabel      matlab.ui.control.Label
        MasseliniqueEditField           matlab.ui.control.EditField
        LongueurEditFieldLabel          matlab.ui.control.Label
        LongueurEditField               matlab.ui.control.EditField
        FrottementEditFieldLabel        matlab.ui.control.Label
        FrottementEditField             matlab.ui.control.EditField
        TensioninitialeEditFieldLabel   matlab.ui.control.Label
        TensioninitialeEditField        matlab.ui.control.EditField
        x_0EditFieldLabel               matlab.ui.control.Label
        x_0EditField                    matlab.ui.control.EditField
        v_0EditFieldLabel               matlab.ui.control.Label
        v_0EditField                    matlab.ui.control.EditField
        PositionrelativeEditFieldLabel  matlab.ui.control.Label
        ImpulsionPositionrelativeEditField  matlab.ui.control.NumericEditField
        ImpulsionSwitchLabel            matlab.ui.control.Label
        ImpulsionSwitch                 matlab.ui.control.Switch
        ConfigurationTab                matlab.ui.container.Tab
        PositionducapteurEditField_2Label  matlab.ui.control.Label
        PositionducapteurEditField      matlab.ui.control.EditField
        PositiondelactuateurEditField_2Label  matlab.ui.control.Label
        PositiondelactuateurEditField   matlab.ui.control.EditField
        PolaritdelartroactionEditFieldLabel  matlab.ui.control.Label
        PolaritdelartroactionEditField  matlab.ui.control.EditField
        DistancecapteurcordeEditFieldLabel  matlab.ui.control.Label
        DistancecapteurcordeEditField   matlab.ui.control.EditField
        ActuateurFMEditFieldLabel       matlab.ui.control.Label
        ActuateurFMEditField            matlab.ui.control.EditField
        ActuateurRLEditFieldLabel       matlab.ui.control.Label
        ActuateurRLEditField            matlab.ui.control.EditField
        CapteurR3EditFieldLabel         matlab.ui.control.Label
        CapteurR3EditField              matlab.ui.control.EditField
        CircuitPassehautEditFieldLabel  matlab.ui.control.Label
        CircuitPassehautEditField       matlab.ui.control.EditField
        CircuitPassebasEditFieldLabel   matlab.ui.control.Label
        CircuitPassebasEditField        matlab.ui.control.EditField
        RgulateurgainEditFieldLabel     matlab.ui.control.Label
        RgulateurgainEditField          matlab.ui.control.EditField
        ServoclefEditFieldLabel         matlab.ui.control.Label
        ServoclefEditField              matlab.ui.control.EditField
        FreqMesTransfEditFieldLabel     matlab.ui.control.Label
        FreqMesTransfEditField          matlab.ui.control.EditField
        AvancTab                        matlab.ui.container.Tab
        PasdesimulationsEditFieldLabel  matlab.ui.control.Label
        PasdesimulationsEditField       matlab.ui.control.EditField
        NombredesegmentsLabel           matlab.ui.control.Label
        NombredesegmentsEditField       matlab.ui.control.EditField
        NotesTab                        matlab.ui.container.Tab
        CommentaireTextAreaLabel        matlab.ui.control.Label
        CommentaireTextArea             matlab.ui.control.TextArea
        FrquencevoulueHzLabel           matlab.ui.control.Label
        FrquencevoulueHzEditField       matlab.ui.control.EditField
        DuredesimulationLabel           matlab.ui.control.Label
        DuredesimulationEditField       matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        SimulationParams SimulationParameterManager;% Description
        Logger ErrorLogger;
        simulatorManager SimulatorManager;
    end
    
    methods (Access = private)
        
        function results = ParseDoublesStrOrExpr(app, value)
            try
                splittedValue =  strsplit(value,';');
                valuesAsDouble = cellfun( @(x) str2double(x),splittedValue);
                if(size(valuesAsDouble,2)==1)
                    if(isnan(valuesAsDouble))
                        results = eval(value);
                    else
                        results(1) = valuesAsDouble;
                    end
                elseif(any(arrayfun(@(x) isnan(x), valuesAsDouble)))
                    results = eval(value);
                else
                    results = valuesAsDouble;
                end
            catch ME
                app.Logger.Warn(ME.message);
                results = double.empty(0);
            end
        end
        
        function SetStatut(app, statut)
            app.StatutLabel.Text = sprintf('Statut : %s', mlreportgen.utils.toString(statut));
        end
        
        function results = SetParsedValue(app,field)
            results = ParseDoublesStrOrExpr(app, field.Value);
            field.Value = app1.FormatDoubleArrayToString(results);
        end
        
        function UpdateFieldsToSimulationParams(app)
            app.DuredesimulationEditField.Value = app.SimulationParams.Duration;
            app.FrquencevoulueHzEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.Frequence_final);
            app.MasseliniqueEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.LinearDensity);
            app.LongueurEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.Length);
            app.PositionducapteurEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.pos_capteur_relative);
            app.PositiondelactuateurEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.pos_actuateur_relative);
            app.PolaritdelartroactionEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.polarite);
            app.FrottementEditField.Value =app1.FormatDoubleArrayToString(app.SimulationParams.frottement);
            app.TensioninitialeEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.T_0);
            app.x_0EditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.x_0);
            app.v_0EditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.v_0);
            app.DistancecapteurcordeEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.position_centre);
            app.PasdesimulationsEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.dt);
            app.NombredesegmentsEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.N);
            
            if app.SimulationParams.isImpulseOn
                app.ImpulsionSwitch.Value = 'On' ;
            else
                app.ImpulsionSwitch.Value = 'Off' ;
            end
            app.ImpulsionPositionrelativeEditField.Enable = app.SimulationParams.isImpulseOn;
            app.ImpulsionPositionrelativeEditField.Value = app.SimulationParams.impulse_relative_position;
            app.ActuateurFMEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.actuateur_force_magnetique);
            app.ActuateurRLEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.actuateur_circuitRL_s);
            app.CapteurR3EditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.capteur_R3);
            app.CircuitPassehautEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.circuit_passe_haut);
            app.CircuitPassebasEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.circuit_passe_bas);
            app.RgulateurgainEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.regulateur_gain);
            app.ServoclefEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.servoclef_clef);
            app.FreqMesTransfEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.freq_mes_transfer);
        end
    end
    
    methods (Static)
        function resultStr = FormatDoubleArrayToString(values)
            resultStr = strjoin(arrayfun(@(value) sprintf('%.3g',value), values, 'UniformOutput', false),';');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Logger = ErrorLogger(app.StatutLabel, app.UIFigure);
            app.SimulationParams = SimulationParameterManager();
            simulator = SystemSimulator();
            app.simulatorManager = SimulatorManager(simulator);
            app.Reset(); %Set everything to default.
        end

        % Button pushed function: SimulerButton
        function Simulate(app, event)
            %Set status
            app.SetStatut("Simulation en cours.");
            app.SimulerButton.Enable = false;
            app.ChargerButton.Enable = false;
            %Start simulation
            app.simulatorManager.Simulate(app.SimulationParams.EnumerateSimulationParameters());
            app.EnregistrerButton.Enable = true;
            app.ChargerButton.Enable = true;
            app.SimulerButton.Enable = true;
            app.SetStatut(app.SimulationParams.GetNumberOfSimulation());
        end

        % Button pushed function: RinitialiserButton
        function Reset(app, event)
            %Set component status
            app.SimulerButton.Enable = true;
            app.ChargerButton.Enable = true;
            app.EnregistrerButton.Enable = false;
            %Set default value. Might want to make a method out of this.
            % -> Factory
            %They have to respect matlab array or split with ';' - Never
            %mind though. Pos could be multiple of L though.
            app.SimulationParams.SetToDefault();
            UpdateFieldsToSimulationParams(app);
            app.SetStatut("Prêt.");
        end

        % Value changed function: FrquencevoulueHzEditField
        function EditFrequence(app, event)
            app.SimulationParams.Frequence_final = SetParsedValue(app, app.FrquencevoulueHzEditField);
        end

        % Value changed function: MasseliniqueEditField
        function EditMassLineique(app, event)
            app.SimulationParams.LinearDensity = SetParsedValue(app, app.MasseliniqueEditField);
        end

        % Value changed function: LongueurEditField
        function EditLongueur(app, event)
            app.SimulationParams.Length = SetParsedValue(app, app.LongueurEditField);
        end

        % Value changed function: DuredesimulationEditField
        function EditDuree(app, event)
            app.SimulationParams.Duration = app.DuredesimulationEditField.Value;
        end

        % Value changed function: PositionducapteurEditField
        function EditPositionCapteur(app, event)
            app.SimulationParams.pos_capteur_relative = SetParsedValue(app, app.PositionducapteurEditField);
        end

        % Value changed function: PositiondelactuateurEditField
        function EditPositionActuateur(app, event)
            app.SimulationParams.pos_actuateur_relative = SetParsedValue(app, app.PositiondelactuateurEditField);
        end

        % Value changed function: PolaritdelartroactionEditField
        function PolaritdelartroactionEditFieldValueChanged(app, event)
            app.SimulationParams.polarite = SetParsedValue(app, app.PolaritdelartroactionEditField);
        end

        % Value changed function: FrottementEditField
        function FrottementEditFieldValueChanged(app, event)
            app.SimulationParams.frottement = SetParsedValue(app, app.FrottementEditField);
        end

        % Value changed function: TensioninitialeEditField
        function TensioninitialeEditFieldValueChanged(app, event)
            app.SimulationParams.T_0 = SetParsedValue(app, app.TensioninitialeEditField);
        end

        % Value changed function: x_0EditField
        function x_0EditFieldValueChanged(app, event)
            app.SimulationParams.x_0 = SetParsedValue(app, app.x_0EditField);
        end

        % Value changed function: v_0EditField
        function v_0EditFieldValueChanged(app, event)
            app.SimulationParams.v_0 = SetParsedValue(app, app.v_0EditField);
        end

        % Value changed function: DistancecapteurcordeEditField
        function DistancecapteurcordeEditFieldValueChanged(app, event)
            app.SimulationParams.position_centre = SetParsedValue(app, app.DistancecapteurcordeEditField);
        end

        % Value changed function: PasdesimulationsEditField
        function PasdesimulationsEditFieldValueChanged(app, event)
            app.SimulationParams.dt = SetParsedValue(app, app.PasdesimulationsEditField);
        end

        % Value changed function: NombredesegmentsEditField
        function NombredesegmentsEditFieldValueChanged(app, event)
            app.SimulationParams.N = SetParsedValue(app, app.NombredesegmentsEditField);
        end

        % Button pushed function: EnregistrerButton
        function EnregistrerButtonPushed(app, event)
            %Create json
            [file,path,~] = uiputfile(['datas ' datestr(now, 'yyyymmddhhMMss') '.mat'], "Choisissez le nom du fichier pour enregistrer les données.");
            if ~isequal(file,0)
                params = app.SimulationParams;
                note = app.CommentaireTextArea.Value;
                save([path file], 'params', 'note');
            end
        end

        % Button pushed function: ChargerButton
        function ChargerButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', "Sélectionez la simulation à charger.");
            if ~isequal(file,0)
                load([path file], 'params', 'note');
                app.SimulationParams = params;
                app.CommentaireTextArea.Value = note;
                UpdateFieldsToSimulationParams(app);
                app.SetStatut("Prêt.");
                app.EnregistrerButton.Enable = true;
                app.ChargerButton.Enable = true;
                app.SimulerButton.Enable = true;
            end
        end

        % Value changed function: ImpulsionSwitch
        function ImpulsionSwitchValueChanged(app, event)
            value = app.ImpulsionSwitch.Value;
            if(value == "On")
                app.ImpulsionPositionrelativeEditField.Enable = true;
                app.SimulationParams.isImpulseOn = true;
            else
                app.ImpulsionPositionrelativeEditField.Enable = false;
                app.SimulationParams.isImpulseOn = false;
            end
        end

        % Value changed function: ImpulsionPositionrelativeEditField
        function ImpulsionPositionrelativeEditFieldValueChanged(app, event)
            app.SimulationParams.impulse_relative_position = SetParsedValue(app, app.ImpulsionPositionrelativeEditField);
        end

        % Value changed function: ActuateurFMEditField
        function ActuateurFMEditFieldValueChanged(app, event)
            app.SimulationParams.actuateur_force_magnetique = SetParsedValue(app, app.ActuateurFMEditField);
        end

        % Value changed function: ActuateurRLEditField
        function ActuateurRLEditFieldValueChanged(app, event)
            app.SimulationParams.actuateur_circuitRL_s = SetParsedValue(app, app.ActuateurRLEditField);
        end

        % Value changed function: CapteurR3EditField
        function CapteurR3EditFieldValueChanged(app, event)
            app.SimulationParams.capteur_R3 = SetParsedValue(app, app.CapteurR3EditField);
        end

        % Value changed function: CircuitPassehautEditField
        function CircuitPassehautEditFieldValueChanged(app, event)
            app.SimulationParams.circuit_passe_haut = SetParsedValue(app, app.CircuitPassehautEditField);
        end

        % Value changed function: CircuitPassebasEditField
        function CircuitPassebasEditFieldValueChanged(app, event)
            app.SimulationParams.circuit_passe_bas = SetParsedValue(app, app.CircuitPassebasEditField);
        end

        % Value changed function: RgulateurgainEditField
        function RgulateurgainEditFieldValueChanged(app, event)
            app.SimulationParams.regulateur_gain = SetParsedValue(app, app.RgulateurgainEditField);
        end

        % Value changed function: ServoclefEditField
        function ServoclefEditFieldValueChanged(app, event)
            app.SimulationParams.servoclef_clef = SetParsedValue(app, app.ServoclefEditField);
        end

        % Value changed function: FreqMesTransfEditField
        function FreqMesTransfEditFieldValueChanged(app, event)
            app.SimulationParams.freq_mes_transfer = SetParsedValue(app, app.FreqMesTransfEditField);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ResultGroup = uitabgroup(app.UIFigure);
            app.RsumTab = uitab(app.ResultGroup);
            app.RsumTab.Title = 'Résumé';
            app.Image = uiimage(app.RsumTab);
            app.Image.ImageSource = 'Design2-SystemeblocIO.jpg';
            app.RsultatsTab = uitab(app.ResultGroup);
            app.RsultatsTab.Title = 'Résultats';
            app.LesAccords2Label = uilabel(app.UIFigure);
            app.LesAccords2Label.Text = 'Les Accords2';
            app.SimulerButton = uibutton(app.UIFigure, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @Simulate, true);
            app.SimulerButton.Text = 'Simuler';
            app.EnregistrerButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerButtonPushed, true);
            app.EnregistrerButton.Enable = 'off';
            app.EnregistrerButton.Text = 'Enregistrer';
            app.ChargerButton = uibutton(app.UIFigure, 'push');
            app.ChargerButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerButtonPushed, true);
            app.ChargerButton.Enable = 'off';
            app.ChargerButton.Text = 'Charger';
            app.RinitialiserButton = uibutton(app.UIFigure, 'push');
            app.RinitialiserButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.RinitialiserButton.Text = 'Réinitialiser';
            app.StatutLabel = uilabel(app.UIFigure);
            app.StatutLabel.Text = 'Statut';
            app.ParameterGroup = uitabgroup(app.UIFigure);
            app.IntrantsTab = uitab(app.ParameterGroup);
            app.IntrantsTab.Title = 'Intrants';
            app.MasseliniqueEditFieldLabel = uilabel(app.IntrantsTab);
            app.MasseliniqueEditFieldLabel.Text = 'Masse linéique';
            app.MasseliniqueEditField = uieditfield(app.IntrantsTab, 'text');
            app.MasseliniqueEditField.ValueChangedFcn = createCallbackFcn(app, @EditMassLineique, true);
            app.LongueurEditFieldLabel = uilabel(app.IntrantsTab);
            app.LongueurEditFieldLabel.Text = 'Longueur';
            app.LongueurEditField = uieditfield(app.IntrantsTab, 'text');
            app.LongueurEditField.ValueChangedFcn = createCallbackFcn(app, @EditLongueur, true);
            app.FrottementEditFieldLabel = uilabel(app.IntrantsTab);
            app.FrottementEditFieldLabel.Text = 'Frottement';
            app.FrottementEditField = uieditfield(app.IntrantsTab, 'text');
            app.FrottementEditField.ValueChangedFcn = createCallbackFcn(app, @FrottementEditFieldValueChanged, true);
            app.TensioninitialeEditFieldLabel = uilabel(app.IntrantsTab);
            app.TensioninitialeEditFieldLabel.Text = 'Tension initiale';
            app.TensioninitialeEditField = uieditfield(app.IntrantsTab, 'text');
            app.TensioninitialeEditField.ValueChangedFcn = createCallbackFcn(app, @TensioninitialeEditFieldValueChanged, true);
            app.x_0EditFieldLabel = uilabel(app.IntrantsTab);
            app.x_0EditFieldLabel.Text = 'x_0';
            app.x_0EditField = uieditfield(app.IntrantsTab, 'text');
            app.x_0EditField.ValueChangedFcn = createCallbackFcn(app, @x_0EditFieldValueChanged, true);
            app.v_0EditFieldLabel = uilabel(app.IntrantsTab);
            app.v_0EditFieldLabel.Text = 'v_0';
            app.v_0EditField = uieditfield(app.IntrantsTab, 'text');
            app.v_0EditField.ValueChangedFcn = createCallbackFcn(app, @v_0EditFieldValueChanged, true);
            app.v_0EditField.Tooltip = {'jajaja'};
            app.PositionrelativeEditFieldLabel = uilabel(app.IntrantsTab);
            app.PositionrelativeEditFieldLabel.Text = 'Position relative';
            app.ImpulsionPositionrelativeEditField = uieditfield(app.IntrantsTab, 'numeric');
            app.ImpulsionPositionrelativeEditField.ValueChangedFcn = createCallbackFcn(app, @ImpulsionPositionrelativeEditFieldValueChanged, true);
            app.ImpulsionSwitchLabel = uilabel(app.IntrantsTab);
            app.ImpulsionSwitchLabel.Text = 'Impulsion';
            app.ImpulsionSwitch = uiswitch(app.IntrantsTab, 'slider');
            app.ImpulsionSwitch.ValueChangedFcn = createCallbackFcn(app, @ImpulsionSwitchValueChanged, true);
            app.ConfigurationTab = uitab(app.ParameterGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.PositionducapteurEditField_2Label = uilabel(app.ConfigurationTab);
            app.PositionducapteurEditField_2Label.Text = 'Position du capteur';
            app.PositionducapteurEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PositionducapteurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionCapteur, true);
            app.PositiondelactuateurEditField_2Label = uilabel(app.ConfigurationTab);
            app.PositiondelactuateurEditField_2Label.Text = 'Position de l''actuateur';
            app.PositiondelactuateurEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PositiondelactuateurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionActuateur, true);
            app.PolaritdelartroactionEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PolaritdelartroactionEditFieldLabel.Enable = 'off';
            app.PolaritdelartroactionEditFieldLabel.Text = 'Polarité de la rétroaction';
            app.PolaritdelartroactionEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PolaritdelartroactionEditField.ValueChangedFcn = createCallbackFcn(app, @PolaritdelartroactionEditFieldValueChanged, true);
            app.PolaritdelartroactionEditField.Enable = 'off';
            app.DistancecapteurcordeEditFieldLabel = uilabel(app.ConfigurationTab);
            app.DistancecapteurcordeEditFieldLabel.Text = {'Distance '; 'capteur-corde'};
            app.DistancecapteurcordeEditField = uieditfield(app.ConfigurationTab, 'text');
            app.DistancecapteurcordeEditField.ValueChangedFcn = createCallbackFcn(app, @DistancecapteurcordeEditFieldValueChanged, true);
            app.AvancTab = uitab(app.ParameterGroup);
            app.AvancTab.Title = 'Avancé';
            app.PasdesimulationsEditFieldLabel = uilabel(app.AvancTab);
            app.PasdesimulationsEditFieldLabel.Text = {'Pas de '; 'simulation (s)'};
            app.PasdesimulationsEditField = uieditfield(app.AvancTab, 'text');
            app.PasdesimulationsEditField.ValueChangedFcn = createCallbackFcn(app, @PasdesimulationsEditFieldValueChanged, true);
            app.NombredesegmentsLabel = uilabel(app.AvancTab);
            app.NombredesegmentsLabel.Text = {'Nombre '; 'de segments'};
            app.NombredesegmentsEditField = uieditfield(app.AvancTab, 'text');
            app.NombredesegmentsEditField.ValueChangedFcn = createCallbackFcn(app, @NombredesegmentsEditFieldValueChanged, true);
            app.ActuateurFMEditFieldLabel = uilabel(app.AvancTab);
            app.ActuateurFMEditFieldLabel.Text = 'Actuateur FM';
            app.ActuateurFMEditField = uieditfield(app.AvancTab, 'text');
            app.ActuateurFMEditField.ValueChangedFcn = createCallbackFcn(app, @ActuateurFMEditFieldValueChanged, true);
            app.ActuateurRLEditFieldLabel = uilabel(app.AvancTab);
            app.ActuateurRLEditFieldLabel.Text = 'Actuateur RL';
            app.ActuateurRLEditField = uieditfield(app.AvancTab, 'text');
            app.ActuateurRLEditField.ValueChangedFcn = createCallbackFcn(app, @ActuateurRLEditFieldValueChanged, true);
            app.CapteurR3EditFieldLabel = uilabel(app.AvancTab);
            app.CapteurR3EditFieldLabel.Text = 'Capteur R3';
            app.CapteurR3EditField = uieditfield(app.AvancTab, 'text');
            app.CapteurR3EditField.ValueChangedFcn = createCallbackFcn(app, @CapteurR3EditFieldValueChanged, true);
            app.CircuitPassehautEditFieldLabel = uilabel(app.AvancTab);
            app.CircuitPassehautEditFieldLabel.Text = 'Circuit Passe-haut';
            app.CircuitPassehautEditField = uieditfield(app.AvancTab, 'text');
            app.CircuitPassehautEditField.ValueChangedFcn = createCallbackFcn(app, @CircuitPassehautEditFieldValueChanged, true);
            app.CircuitPassebasEditFieldLabel = uilabel(app.AvancTab);
            app.CircuitPassebasEditFieldLabel.Text = 'Circuit Passe-bas';
            app.CircuitPassebasEditField = uieditfield(app.AvancTab, 'text');
            app.CircuitPassebasEditField.ValueChangedFcn = createCallbackFcn(app, @CircuitPassebasEditFieldValueChanged, true);
            app.RgulateurgainEditFieldLabel = uilabel(app.AvancTab);
            app.RgulateurgainEditFieldLabel.Text = 'Régulateur gain';
            app.RgulateurgainEditField = uieditfield(app.AvancTab, 'text');
            app.RgulateurgainEditField.ValueChangedFcn = createCallbackFcn(app, @RgulateurgainEditFieldValueChanged, true);
            app.ServoclefEditFieldLabel = uilabel(app.AvancTab);
            app.ServoclefEditFieldLabel.Text = 'Servo clef';
            app.ServoclefEditField = uieditfield(app.AvancTab, 'text');
            app.ServoclefEditField.ValueChangedFcn = createCallbackFcn(app, @ServoclefEditFieldValueChanged, true);
            app.FreqMesTransfEditFieldLabel = uilabel(app.AvancTab);
            app.FreqMesTransfEditFieldLabel.Text = 'Freq. Mes. Transf';
            app.FreqMesTransfEditField = uieditfield(app.AvancTab, 'text');
            app.FreqMesTransfEditField.ValueChangedFcn = createCallbackFcn(app, @FreqMesTransfEditFieldValueChanged, true);
            app.NotesTab = uitab(app.ParameterGroup);
            app.NotesTab.Title = 'Notes';
            app.CommentaireTextAreaLabel = uilabel(app.NotesTab);
            app.CommentaireTextAreaLabel.Text = 'Commentaire';
            app.CommentaireTextArea = uitextarea(app.NotesTab);
            app.FrquencevoulueHzLabel = uilabel(app.UIFigure);
            app.FrquencevoulueHzLabel.Text = {'Fréquence '; 'voulue (Hz)'};
            app.FrquencevoulueHzEditField = uieditfield(app.UIFigure, 'text');
            app.FrquencevoulueHzEditField.ValueChangedFcn = createCallbackFcn(app, @EditFrequence, true);
            app.DuredesimulationLabel = uilabel(app.UIFigure);
            app.DuredesimulationLabel.Text = {'Durée de '; 'simulation'};
            app.DuredesimulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DuredesimulationEditField.ValueChangedFcn = createCallbackFcn(app, @EditDuree, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end